classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PLAYButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ispy = imread('img/ispy.png');
            imshow(ispy, "Parent", app.UIAxes);
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % IMPORTANT: UPDATE as new images are added
            % String array of all images in the img directory
            images = ["img/gameimages/bedroom.jpg" "img/gameimages/livingroom.jpg" "img/gameimages/clutter.jpeg" "img/gameimages/pinkstudy.jpg" "img/gameimages/boxes.png"]
            index = int8(length(images)*rand)
            % Case for minor bug where index of 0 will cause the program to
            % crash, switches index of 0 to index of 1
            if index == 0
                index = 1
            end
            imagefile = images(index);
            x = imread(imagefile);
            imshow (x, "Parent", app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end